classdef receiverapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        STATUSLabel                 matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        DurationField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel      matlab.ui.control.Label
        SamplingRateField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel  matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        UIAxes_Spectrogram_4        matlab.ui.control.UIAxes
        UIAxes_Spectrogram_3        matlab.ui.control.UIAxes
        UIAxes_Spectrogram_2        matlab.ui.control.UIAxes
        UIAxes_Spectrogram          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ReceivedSpectrogram    cell
        Category               double
        DecodedMessage         string
    end
    
    % Callbacks that handle component events
    methods (Access = private)
            % App'in başlangıcında çalışacak fonksiyon

        % Button pushed function for Record
        % function RecordButtonPushed(app, ~)
        %     try
        %         % Get parameters from UI
        %         duration = app.DurationField.Value;
        %         samplingRate = app.SamplingRateField.Value;
        % 
        %         % Call receiver function
        %         app.ReceivedSpectrogram = receiver(samplingRate, duration);
        % 
        %         % Plot spectrogram
        %         app.plotSpectrogram();
        % 
        %         app.StatusLabel.Text = 'Recording and analysis complete.';
        %     catch ME
        %         app.StatusLabel.Text = ['Error: ', ME.message];
        %     end
        % end
        % 
        % % Method to plot spectrogram
        % function plotSpectrogram(app)
        %     % Extract spectrogram parameters
        %     signal = app.ReceivedSpectrogram{1};

        %     window = app.ReceivedSpectrogram{2};
        %     overlap = app.ReceivedSpectrogram{3};
        %     fftLength = app.ReceivedSpectrogram{4};
        %     samplingRate = app.ReceivedSpectrogram{5};
        %     minFreq = app.ReceivedSpectrogram{6};
        %     maxFreq = app.ReceivedSpectrogram{7};
        % 
        %     % Plot original spectrogram
        %     spectrogram(signal, window, overlap, fftLength, samplingRate, 'yaxis', 'Parent', app.SpectrogramAxes);
        %     title(app.SpectrogramAxes, 'Received Signal Spectrogram');
        %     xlabel(app.SpectrogramAxes, 'Time (s)');
        %     ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
        % 
        %     % Set y-axis limits based on detected frequency range
        %     ylim(app.SpectrogramAxes, [minFreq-1000, maxFreq+1000]);
        % end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        app.UIAxes_Spectrogram.Visible = 'off';
                        app.UIAxes_Spectrogram_2.Visible = 'off';
            app.UIAxes_Spectrogram_3.Visible = 'off';
            app.UIAxes_Spectrogram_4.Visible = 'off';

        end

        % Callback function
        function SpectrogramAxesButtonDown(app, event)
      
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %app.UIAxes_Spectrogram.Visible = 'off';
            duration = app.DurationField.Value;
            samplingrate = app.SamplingRateField.Value;
            [rxspectrogram,rxspectrogramnon,category] = reciever(samplingrate,duration,app);
%%          filtered
            
            [stftData, freq, time] = spectrogram(rxspectrogram{1}, rxspectrogram{2}, rxspectrogram{3}, rxspectrogram{4}, rxspectrogram{5});
            magnitudeSpectrum = 20 * log10(abs(stftData));
            axes(app.UIAxes_Spectrogram); % GUI'deki Axes bileşenini kullan
            imagesc(app.UIAxes_Spectrogram, time, freq/1e3, magnitudeSpectrum); % Frekansı kHz cinsine çevir
            app.UIAxes_Spectrogram.Visible = 'on';
            axis(app.UIAxes_Spectrogram, 'xy'); % Y eksenini doğru göstermek için
            colormap(app.UIAxes_Spectrogram, 'jet'); % Daha iyi görsellik için
            colorbar(app.UIAxes_Spectrogram); % Spektrum yoğunluğu için renk çubuğu
            caxis(app.UIAxes_Spectrogram, [max(magnitudeSpectrum(:)) - 60, max(magnitudeSpectrum(:))]); % Görüntü sınırlarını ayarla
            
            % Başlık ve etiketler
            title(app.UIAxes_Spectrogram, ['Zoomed Spectrogram of Recieved FHSS Signal With Noise Reduction - Category ', num2str(category)]);
            xlabel(app.UIAxes_Spectrogram, 'Time (s)');
            ylabel(app.UIAxes_Spectrogram, 'Frequency (kHz)');
            ylim(app.UIAxes_Spectrogram, [rxspectrogram{6}]);
            %%
            axes(app.UIAxes_Spectrogram_3); % GUI'deki Axes bileşenini kullan
            imagesc(app.UIAxes_Spectrogram_3, time, freq/1e3, magnitudeSpectrum); % Frekansı kHz cinsine çevir
            app.UIAxes_Spectrogram_3.Visible = 'on';
            axis(app.UIAxes_Spectrogram_3, 'xy'); % Y eksenini doğru göstermek için
            colormap(app.UIAxes_Spectrogram_3, 'jet'); % Daha iyi görsellik için
            colorbar(app.UIAxes_Spectrogram_3); % Spektrum yoğunluğu için renk çubuğu
            caxis(app.UIAxes_Spectrogram_3, [max(magnitudeSpectrum(:)) - 60, max(magnitudeSpectrum(:))]); % Görüntü sınırlarını ayarla
            
            % Başlık ve etiketler
            title(app.UIAxes_Spectrogram_3, ['Spectrogram of Recieved FHSS Signal With Noise Reduction - Category ', num2str(category)]);
            xlabel(app.UIAxes_Spectrogram_3, 'Time (s)');
            ylabel(app.UIAxes_Spectrogram_3, 'Frequency (kHz)');
            ylim(app.UIAxes_Spectrogram_3, [0, 22]);
            %% not filtered


            [stftData, freq, time] = spectrogram(rxspectrogramnon{1}, rxspectrogramnon{2}, rxspectrogramnon{3}, rxspectrogramnon{4}, rxspectrogramnon{5});
            magnitudeSpectrum = 20 * log10(abs(stftData));
            axes(app.UIAxes_Spectrogram_2); % GUI'deki Axes bileşenini kullan
            imagesc(app.UIAxes_Spectrogram_2, time, freq/1e3, magnitudeSpectrum); % Frekansı kHz cinsine çevir
            app.UIAxes_Spectrogram_2.Visible = 'on';
            axis(app.UIAxes_Spectrogram_2, 'xy'); % Y eksenini doğru göstermek için
            colormap(app.UIAxes_Spectrogram_2, 'jet'); % Daha iyi görsellik için
            colorbar(app.UIAxes_Spectrogram_2); % Spektrum yoğunluğu için renk çubuğu
            caxis(app.UIAxes_Spectrogram_2, [max(magnitudeSpectrum(:)) - 60, max(magnitudeSpectrum(:))]); % Görüntü sınırlarını ayarla
            
            % Başlık ve etiketler
            title(app.UIAxes_Spectrogram_2, ['Zoomed Spectrogram of Recieved FHSS Signal Without Noise Reduction - Category ', num2str(category)]);
            xlabel(app.UIAxes_Spectrogram_2, 'Time (s)');
            ylabel(app.UIAxes_Spectrogram_2, 'Frequency (kHz)');
            ylim(app.UIAxes_Spectrogram_2, [rxspectrogram{6}]);
            %%
            axes(app.UIAxes_Spectrogram_4); % GUI'deki Axes bileşenini kullan
            imagesc(app.UIAxes_Spectrogram_4, time, freq/1e3, magnitudeSpectrum); % Frekansı kHz cinsine çevir
            app.UIAxes_Spectrogram_4.Visible = 'on';
            axis(app.UIAxes_Spectrogram_4, 'xy'); % Y eksenini doğru göstermek için
            colormap(app.UIAxes_Spectrogram_4, 'jet'); % Daha iyi görsellik için
            colorbar(app.UIAxes_Spectrogram_4); % Spektrum yoğunluğu için renk çubuğu
            caxis(app.UIAxes_Spectrogram_4, [max(magnitudeSpectrum(:)) - 60, max(magnitudeSpectrum(:))]); % Görüntü sınırlarını ayarla
            
            % Başlık ve etiketler
            title(app.UIAxes_Spectrogram_4, ['Spectrogram of Recieved FHSS Signal Without Noise Reduction - Category ', num2str(category)]);
            xlabel(app.UIAxes_Spectrogram_4, 'Time (s)');
            ylabel(app.UIAxes_Spectrogram_4, 'Frequency (kHz)');
            ylim(app.UIAxes_Spectrogram_4, [0, 22]);
%%
            % Kullanıcıya bildirim


            pause(1)
        % Button pushed function: Save Screenshot Button
            % Ekran görüntüsünü kaydet
            exportgraphics(app.UIFigure, 'GUI_screenshot.png', 'Resolution', 300); % PNG formatında kaydeder
            disp('Ekran görüntüsü kaydedildi.');
        
            disp('Spectrogram GUI üzerinde görüntülendi.');

        end

        % Button down function: UIAxes_Spectrogram
        function UIAxes_SpectrogramButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Spectrogram = uiaxes(app.UIFigure);
            title(app.UIAxes_Spectrogram, 'Title')
            xlabel(app.UIAxes_Spectrogram, 'X')
            ylabel(app.UIAxes_Spectrogram, 'Y')
            zlabel(app.UIAxes_Spectrogram, 'Z')
            app.UIAxes_Spectrogram.ButtonDownFcn = createCallbackFcn(app, @UIAxes_SpectrogramButtonDown, true);
            app.UIAxes_Spectrogram_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_Spectrogram_2, 'Title')
            xlabel(app.UIAxes_Spectrogram_2, 'X')
            ylabel(app.UIAxes_Spectrogram_2, 'Y')
            zlabel(app.UIAxes_Spectrogram_2, 'Z')
            app.UIAxes_Spectrogram_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_Spectrogram_3, 'Title')
            xlabel(app.UIAxes_Spectrogram_3, 'X')
            ylabel(app.UIAxes_Spectrogram_3, 'Y')
            zlabel(app.UIAxes_Spectrogram_3, 'Z')
            app.UIAxes_Spectrogram_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_Spectrogram_4, 'Title')
            xlabel(app.UIAxes_Spectrogram_4, 'X')
            ylabel(app.UIAxes_Spectrogram_4, 'Y')
            zlabel(app.UIAxes_Spectrogram_4, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.STATUSLabel = uilabel(app.UIFigure);
            app.STATUSLabel.Text = 'STATUS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = receiverapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end