classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SendSignalButton             matlab.ui.control.Button
        Button_Run_2                 matlab.ui.control.Button
        fftLengthEditField           matlab.ui.control.EditField
        fftLengthEditFieldLabel      matlab.ui.control.Label
        overlapLengthEditField       matlab.ui.control.EditField
        overlapLengthEditFieldLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        STFTParametersLabel          matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Button_Run                   matlab.ui.control.Button
        pointHannwindowEditField     matlab.ui.control.EditField
        pointHannwindowLabel         matlab.ui.control.Label
        EditField_StudentNumber      matlab.ui.control.EditField
        StudentNumberEditFieldLabel  matlab.ui.control.Label
        TextArea_Message             matlab.ui.control.TextArea
        MessageTextAreaLabel         matlab.ui.control.Label
        DropDown_Category            matlab.ui.control.DropDown
        CategoryDropDownLabel        matlab.ui.control.Label
        EditField_SamplingRate       matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel   matlab.ui.control.Label
        UIAxes_Spectrogram           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        trans_spectrogram = [];
        audiosignal=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.trans_spectrogram = [];
            %app.samplingrate = [];
        end

        % Button pushed function: Button_Run
        function Button_RunPushed(app, event)
% Kullanıcıdan alınan değerler

category = str2double(app.DropDown_Category.Value); % Numeric kategori
messagecell = (app.TextArea_Message.Value);
message = messagecell{1};
samplingRate = app.EditField_SamplingRate.Value;
studentNumber = app.EditField_StudentNumber.Value;  % Öğrenci numarası
% transmitter fonksiyonunu çağırma
[audio_signal, txSpectrogram] = transmitter(category, message, samplingRate, studentNumber);
app.trans_spectrogram = txSpectrogram;
%app.samplingrate = samplingRate;
app.audiosignal = audio_signal;

% STFT hesaplaması
app.Label.Text = "Recommended: " + string(txSpectrogram{3});
drawnow
app.Label_2.Text = "Recommended: " + string(txSpectrogram{4});
drawnow
app.Label_3.Text = "Recommended: " + string(txSpectrogram{5});
drawnow


        end

        % Button pushed function: Button_Run_2
        function Button_Run_2Pushed(app, event)
txSpectrogram = app.trans_spectrogram;
txSpectrogram{3} = hann(str2double(app.pointHannwindowEditField.Value));
txSpectrogram{4} = str2double(app.overlapLengthEditField.Value);
txSpectrogram{5} = str2double(app.fftLengthEditField.Value);

[stftData, freq, time] = stft(txSpectrogram{1}, app.EditField_SamplingRate.Value, ...
    'Window', txSpectrogram{3}, 'OverlapLength', txSpectrogram{4}, 'FFTLength', txSpectrogram{5});

% Magnitude spektrumu hesaplama (dB cinsinden göstermek için)
magnitudeSpectrum = 20 * log10(abs(stftData));

% UIAxes üzerine spectrogram çizimi
axes(app.UIAxes_Spectrogram); % GUI'deki Axes bileşenini kullan
imagesc(app.UIAxes_Spectrogram, time, freq/1e3, magnitudeSpectrum); % Frekansı kHz cinsine çevir
axis(app.UIAxes_Spectrogram, 'xy'); % Y eksenini doğru göstermek için
colormap(app.UIAxes_Spectrogram, 'jet'); % Daha iyi görsellik için
colorbar(app.UIAxes_Spectrogram); % Spektrum yoğunluğu için renk çubuğu
caxis(app.UIAxes_Spectrogram, [max(magnitudeSpectrum(:)) - 60, max(magnitudeSpectrum(:))]); % Görüntü sınırlarını ayarla

% Başlık ve etiketler
title(app.UIAxes_Spectrogram, ['Spectrogram of Transmitted FHSS Signal - Category ', num2str(str2double(app.DropDown_Category.Value))], 'FontSize', 20);
xlabel(app.UIAxes_Spectrogram, 'Time (s)', 'FontSize', 20);
ylabel(app.UIAxes_Spectrogram, 'Frequency (kHz)', 'FontSize', 20);
ylim(app.UIAxes_Spectrogram, [(txSpectrogram{6}-1e3)/1e3, (txSpectrogram{7}+1e3)/1e3]);
xlim(app.UIAxes_Spectrogram,[0, max(time)]);
% Colorbar oluşturma
cb = colorbar(app.UIAxes_Spectrogram);

% Colorbar font boyutunu ayarlama
cb.FontSize = 16; % Font boyutunu 12 olarak ayarladım
% Kullanıcıya bildirim
app.UIAxes_Spectrogram.XAxis.FontSize = 14; % X eksenindeki etiket font boyutunu 15 yap
app.UIAxes_Spectrogram.YAxis.FontSize = 14; % Y eksenindeki etiket font boyutunu 15 yap
disp('Spectrogram GUI üzerinde görüntülendi.');  
        end

        % Button pushed function: SendSignalButton
        function SendSignalButtonPushed(app, event)
            disp('FHSS sinyali hoparlörden çalınıyor...');
            sound(app.audiosignal, app.EditField_SamplingRate.Value); % Ses çalma (samplingRate ile)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Spectrogram = uiaxes(app.UIFigure);
            title(app.UIAxes_Spectrogram, 'Title')
            xlabel(app.UIAxes_Spectrogram, 'X')
            ylabel(app.UIAxes_Spectrogram, 'Y')
            zlabel(app.UIAxes_Spectrogram, 'Z')
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.EditField_SamplingRate = uieditfield(app.UIFigure, 'numeric');
            app.CategoryDropDownLabel = uilabel(app.UIFigure);
            app.CategoryDropDownLabel.Text = 'Category';
            app.DropDown_Category = uidropdown(app.UIFigure);
            app.DropDown_Category.Items = {'1', '2', '3'};
            app.DropDown_Category.Value = '1';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.TextArea_Message = uitextarea(app.UIFigure);
            app.StudentNumberEditFieldLabel = uilabel(app.UIFigure);
            app.StudentNumberEditFieldLabel.Text = 'Student Number';
            app.EditField_StudentNumber = uieditfield(app.UIFigure, 'text');
            app.pointHannwindowLabel = uilabel(app.UIFigure);
            app.pointHannwindowLabel.Text = '- point Hann window ';
            app.pointHannwindowEditField = uieditfield(app.UIFigure, 'text');
            app.Button_Run = uibutton(app.UIFigure, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Button_RunPushed, true);
            app.Button_Run.Text = 'Generate FHSS Signal';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.STFTParametersLabel = uilabel(app.UIFigure);
            app.STFTParametersLabel.Text = 'STFT Parameters';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.overlapLengthEditFieldLabel = uilabel(app.UIFigure);
            app.overlapLengthEditFieldLabel.Text = 'overlapLength';
            app.overlapLengthEditField = uieditfield(app.UIFigure, 'text');
            app.fftLengthEditFieldLabel = uilabel(app.UIFigure);
            app.fftLengthEditFieldLabel.Text = 'fftLength';
            app.fftLengthEditField = uieditfield(app.UIFigure, 'text');
            app.Button_Run_2 = uibutton(app.UIFigure, 'push');
            app.Button_Run_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Run_2Pushed, true);
            app.Button_Run_2.Text = 'Plot STFT Spectrogram';
            app.SendSignalButton = uibutton(app.UIFigure, 'push');
            app.SendSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SendSignalButtonPushed, true);
            app.SendSignalButton.Text = 'Send Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end